classdef SoftwareUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MenuTab                         matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        StoButton                       matlab.ui.control.Button
        Button9                         matlab.ui.control.Button
        Button8                         matlab.ui.control.Button
        Button7                         matlab.ui.control.Button
        Button6                         matlab.ui.control.Button
        Button5                         matlab.ui.control.Button
        Button4                         matlab.ui.control.Button
        MaButton                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        ReButton                        matlab.ui.control.Button
        ReTab                           matlab.ui.container.Tab
        MenuButton                      matlab.ui.control.Button
        ReynoldsNumberSimulationButton  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        TheoryLabel                     matlab.ui.control.Label
        ReSimTab                        matlab.ui.container.Tab
        BackButton                      matlab.ui.control.Button
        MenuButton_2                    matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        Slider                          matlab.ui.control.RangeSlider
        SliderLabel                     matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        StoTab                          matlab.ui.container.Tab
        SimulationButton                matlab.ui.control.Button
        MenuButton_3                    matlab.ui.control.Button
        StoSimTab                       matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        StokesNumberSlider              matlab.ui.control.Slider
        StokesNumberSliderLabel         matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        BackButton_2                    matlab.ui.control.Button
        MenuButton_4                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        MaTab                           matlab.ui.container.Tab
        MenuButton_5                    matlab.ui.control.Button
        MaButton_2                      matlab.ui.control.Button
        MaSimTab                        matlab.ui.container.Tab
        BackButton_3                    matlab.ui.control.Button
        MenuButton_6                    matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(app)
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton, ReButton
        function ReButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ReTab
        end

        % Callback function
        function SliderValueChanging(app, event)
            changingValue = event.Value
        end

        % Callback function: RunButton, Slider
        function NewReSim(app, event)
            value = app.Slider.Value;
            [ReynoldsSim,wx,wy] = ReSim();
             plot(app.UIAxes,ReynoldsSim,XData=wx, ...
                YData=wy,NodeLabel=[])
            axis(app.UIAxes,"equal")

        end

        % Button pushed function: BackButton_2, StoButton
        function StoButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.StoTab
        end

        % Button pushed function: BackButton_3, MaButton
        function MaButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.MaTab
        end

        % Button pushed function: ReynoldsNumberSimulationButton
        function ReynoldsNumberSimulationButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ReSimTab
        end

        % Button pushed function: MenuButton, MenuButton_2, MenuButton_3, 
        % ...and 1 other component
        function MenuButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.MenuTab
        end

        % Callback function
        function RunButton_2Pushed(app, event)
            
        end

        % Button pushed function: RunButton_2
        function RunStokesSimButtonPushed(app, event)
            app.UIAxes.Visible = true;
            StSim(app.UIAxes, app.StokesNumberSlider.Value);
        end

        % Button pushed function: SimulationButton
        function SimulationButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.StoSimTab
        end

        % Value changed function: StokesNumberSlider
        function StokesNumberSliderValueChanged(app, event)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.Panel_2 = uipanel(app.MenuTab);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.ReButton = uibutton(app.GridLayout2, 'push');
            app.ReButton.ButtonPushedFcn = createCallbackFcn(app, @ReButtonPushed, true);
            app.ReButton.Text = 'Re';
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.Text = '';
            app.MaButton = uibutton(app.GridLayout2, 'push');
            app.MaButton.ButtonPushedFcn = createCallbackFcn(app, @MaButtonPushed, true);
            app.MaButton.Text = 'Ma';
            app.Button4 = uibutton(app.GridLayout2, 'push');
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.GridLayout2, 'push');
            app.Button5.Text = 'Button5';
            app.Button6 = uibutton(app.GridLayout2, 'push');
            app.Button6.Text = 'Button6';
            app.Button7 = uibutton(app.GridLayout2, 'push');
            app.Button7.Text = 'Button7';
            app.Button8 = uibutton(app.GridLayout2, 'push');
            app.Button8.Text = 'Button8';
            app.Button9 = uibutton(app.GridLayout2, 'push');
            app.Button9.Text = 'Button9';
            app.StoButton = uibutton(app.GridLayout2, 'push');
            app.StoButton.ButtonPushedFcn = createCallbackFcn(app, @StoButtonPushed, true);
            app.StoButton.Text = 'Sto';
            app.ReTab = uitab(app.TabGroup);
            app.ReTab.Title = 'Re';
            app.TheoryLabel = uilabel(app.ReTab);
            app.TheoryLabel.Text = 'Theory';
            app.Label = uilabel(app.ReTab);
            app.Label.Text = 'lorem ipusm fajfjafja fjaopda ajfjoooooooooooooooooooooooooooooooasf';
            app.ReynoldsNumberSimulationButton = uibutton(app.ReTab, 'push');
            app.ReynoldsNumberSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ReynoldsNumberSimulationButtonPushed, true);
            app.ReynoldsNumberSimulationButton.Text = 'Reynolds Number Simulation';
            app.MenuButton = uibutton(app.ReTab, 'push');
            app.MenuButton.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton.Text = 'Menu';
            app.ReSimTab = uitab(app.TabGroup);
            app.ReSimTab.Title = 'ReSim';
            app.UIAxes2 = uiaxes(app.ReSimTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_2 = uiaxes(app.ReSimTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SliderLabel = uilabel(app.ReSimTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.ReSimTab, 'range');
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @NewReSim, true);
            app.RunButton = uibutton(app.ReSimTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @NewReSim, true);
            app.RunButton.Text = 'Run';
            app.MenuButton_2 = uibutton(app.ReSimTab, 'push');
            app.MenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton_2.Text = 'Menu';
            app.BackButton = uibutton(app.ReSimTab, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @ReButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.StoTab = uitab(app.TabGroup);
            app.StoTab.Title = 'Sto';
            app.MenuButton_3 = uibutton(app.StoTab, 'push');
            app.MenuButton_3.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton_3.Text = 'Menu';
            app.SimulationButton = uibutton(app.StoTab, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = 'Simulation';
            app.StoSimTab = uitab(app.TabGroup);
            app.StoSimTab.Title = 'StoSim';
            app.UIAxes = uiaxes(app.StoSimTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.MenuButton_4 = uibutton(app.StoSimTab, 'push');
            app.MenuButton_4.ButtonPushedFcn = createCallbackFcn(app, @MenuButtonPushed, true);
            app.MenuButton_4.Text = 'Menu';
            app.BackButton_2 = uibutton(app.StoSimTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @StoButtonPushed, true);
            app.BackButton_2.Text = 'Back';
            app.RunButton_2 = uibutton(app.StoSimTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunStokesSimButtonPushed, true);
            app.RunButton_2.Text = 'Run';
            app.StokesNumberSliderLabel = uilabel(app.StoSimTab);
            app.StokesNumberSliderLabel.Text = 'Stokes Number';
            app.StokesNumberSlider = uislider(app.StoSimTab);
            app.StokesNumberSlider.Limits = [0.1 10];
            app.StokesNumberSlider.MajorTicks = [0.1 2 4 6 8 10];
            app.StokesNumberSlider.ValueChangedFcn = createCallbackFcn(app, @StokesNumberSliderValueChanged, true);
            app.StokesNumberSlider.Value = 0.1;
            app.Label_2 = uilabel(app.StoSimTab);
            app.Label_2.Text = 'The particles have an initial horizontal velocity and enter a vortex. You will observe that at low St, the particles have weak inertia and are easily redirected by the vortex. At high St, the particles can persist on their original trajectory.';
            app.MaTab = uitab(app.TabGroup);
            app.MaTab.Title = 'Ma';
            app.MaButton_2 = uibutton(app.MaTab, 'push');
            app.MaButton_2.Text = 'Ma';
            app.MenuButton_5 = uibutton(app.MaTab, 'push');
            app.MenuButton_5.Text = 'Menu';
            app.MaSimTab = uitab(app.TabGroup);
            app.MaSimTab.Title = 'MaSim';
            app.UIAxes3 = uiaxes(app.MaSimTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.MenuButton_6 = uibutton(app.MaSimTab, 'push');
            app.MenuButton_6.Text = 'Menu';
            app.BackButton_3 = uibutton(app.MaSimTab, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @MaButtonPushed, true);
            app.BackButton_3.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoftwareUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end